classdef extrusionPpredict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtrusionpressurepredictionUIFigure  matlab.ui.Figure
        AvailablematerialsListBoxLabel  matlab.ui.control.Label
        AvailablematerialsListBox       matlab.ui.control.ListBox
        NozzlesDimensions               matlab.ui.control.Table
        NbNozzleSpinnerLabel            matlab.ui.control.Label
        NbNozzleSpinner                 matlab.ui.control.Spinner
        UIAxesPV                        matlab.ui.control.UIAxes
        SelectmaterialdatabaseButton    matlab.ui.control.Button
        ComputeButton                   matlab.ui.control.Button
        UIAxesNozzleVelocities          matlab.ui.control.UIAxes
        SetallDEditFieldLabel           matlab.ui.control.Label
        SetallD                         matlab.ui.control.NumericEditField
        SetallLLabel                    matlab.ui.control.Label
        SetallL                         matlab.ui.control.NumericEditField
        AmbientPPaLabel                 matlab.ui.control.Label
        Pambient                        matlab.ui.control.NumericEditField
        DesirednozzlevmmsEditFieldLabel  matlab.ui.control.Label
        NozzleVelocities                matlab.ui.control.EditField
        PlotgraphDropDownLabel          matlab.ui.control.Label
        PlotgraphDropDown               matlab.ui.control.DropDown
        PlotforvelocitymmsDropDownLabel  matlab.ui.control.Label
        PlotforvelocitymmsDropDown      matlab.ui.control.DropDown
        PlotfornozzleDropDownLabel      matlab.ui.control.Label
        PlotfornozzleDropDown           matlab.ui.control.DropDown
        ExportgraphButton               matlab.ui.control.Button
        ExportalldataButton             matlab.ui.control.Button
        SelectgraphforexportDropDownLabel  matlab.ui.control.Label
        SelectgraphforexportDropDown    matlab.ui.control.DropDown
        AllCheckBox                     matlab.ui.control.CheckBox
        SaveinputsButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Declaration of variables used by multiple functions
        matFile; % The material file selected by the user
        debug_mode = 0; % To print in the console all the intermediate values for calculation
        
        rho, w, f, n, K, eta_inf, eta_0, tau_0, lambda, a; % Materials variables
        v, P, eta, SR, Q, v_all, q_theo, Errv_real, dRi, dP, deta, dSR; % Intermediate calculation variables
        P_lit = 0;
        SR_lit = 0;
        eta_lit = 0;
        
        prefFileName = 'prefs.txt';
    end
    
    methods (Access = private)
        
        % Updates the console textarea with output messages from the program (not the real matlab console)
        function updateConsole(app,value)
            oldValue = app.ConsoleTextArea.Value;
            if strcmp(oldValue,'')
                app.ConsoleTextArea.Value = value;
            else
                app.ConsoleTextArea.Value = [oldValue;value];
            end
        end
    
        % Load the user's input preferences into the UI
        function loadPreferences(app)
            if isfile(app.prefFileName)
                T = readtable(app.prefFileName);
                app.NbNozzleSpinner.Value = T{1,1};
                app.SetallD.Value = T{1,2};
                app.SetallL.Value = T{1,3};
                app.NozzleVelocities.Value = string(T{1,4});
                app.Pambient.Value = T{1,5};
                DRow = num2cell(str2double(split(T{1,6})'));
                LRow = num2cell(str2double(split(T{1,7})'));
                
                % Update column names
                app.NozzlesDimensions.ColumnName = strcat('Nozzle',{' '},string(1:size(DRow,2)));
                app.NozzlesDimensions.ColumnWidth = num2cell(75*ones(1,size(DRow,2)));
                
                set(app.NozzlesDimensions,'Data',[DRow; LRow]);
            end
        end
        
        % Updates the nozzle dimensions table with new columns
        % new columns take the values of the Set all D and L textboxes
        function updateTable(app,value)
            % Update column names
            app.NozzlesDimensions.ColumnName = strcat('Nozzle',{' '},string(1:value));
            app.NozzlesDimensions.ColumnWidth = num2cell(75*ones(1,value));
            
            % Update data while keeping the old values
            oldData = get(app.NozzlesDimensions,'Data');
            
            nbCols = size(oldData,2);
            delta = value-nbCols;
            
            if(delta>0) % Add new columns with values from the Set all D and L textboxes
                newData = zeros(2,delta);
                newData(1,:) = app.SetallD.Value*ones(1,delta);
                newData(2,:) = app.SetallL.Value*ones(1,delta);
                newData = [oldData num2cell(newData)];
            else % Delete old columns
                oldData(:,nbCols) = [];
                newData = oldData;
            end
            
            % Set the table data property
            set(app.NozzlesDimensions,'Data',newData)
        end
        
        % Changes all the table values, one row at a time, depending on whether D or L was changed.
        function changeTableValues(app,numRow,value)
            keepData = get(app.NozzlesDimensions,'Data');
            newRow = num2cell(value*ones(1,app.NbNozzleSpinner.Value()));
            if numRow==1
                keepRowNum = 2;
            else
                keepRowNum = 1;
            end
            keepDataRow = keepData(keepRowNum,:);
            if numRow==1
                newData = [newRow;keepDataRow];
            else
                newData = [keepDataRow;newRow];
            end
            set(app.NozzlesDimensions,'Data',newData)
        end
        
        % Updates the plot for nozzle drop down menu for the viscosity plot
        function updatePlotForNozzle(app)
            app.PlotfornozzleDropDown.Items = cellstr(string(1:app.NbNozzleSpinner.Value));
        end
        
        % Updates the velocity drop down menu for the nozzle velocities plot
        function updateVelocityDropDown(app)
            app.PlotforvelocitymmsDropDown.Items = cellstr(string(str2num(app.NozzleVelocities.Value)));
        end
    
        % Set the compute button background color to yellow to indicate the user he needs to recompute
        function resetComputeButton(app)
            app.ComputeButton.BackgroundColor = 'yellow';
        end
        
        % Plot the graphs for required pressure vs. desired velocity and true nozzle exit velocities depending on the values of the drop down menus
        function makeGraph(app)
            plotGraph = app.PlotgraphDropDown.Value;
            plotForNozzle = str2double(app.PlotfornozzleDropDown.Value);
            plotForVelo = find(strcmp(app.PlotforvelocitymmsDropDown.Items,app.PlotforvelocitymmsDropDown.Value));
            
            if strcmp(plotGraph,'Pressure')
                comparePlotPV(app.v,app.P,app.v,app.P_lit,[0 0],[0 0],app.dP,'default',app.UIAxesPV);
                app.UIAxesPV.XLabel.String = 'Desired nozzle exit velocity (mm/s)';
                app.UIAxesPV.YLabel.String = 'Required pressure (kPa)';
                app.UIAxesPV.XScale = 'linear';
                app.UIAxesPV.YScale = 'log';
            else
                comparePlotVisco(app.SR(:,plotForNozzle),app.eta(:,plotForNozzle),app.SR_lit,app.eta_lit,[0 0],[0 0],app.deta(:,plotForNozzle),app.dSR(:,plotForNozzle),'default',0,app.UIAxesPV);
                app.UIAxesPV.XLabel.String = 'Process-related apparent shear rate (1/s)';
                app.UIAxesPV.YLabel.String = 'Process-related apparent viscosity (Pa.s)';
                app.UIAxesPV.XScale = 'log';
                app.UIAxesPV.YScale = 'log';
            end
            compareBarPlotV(app.v_all(plotForVelo,:),app.v(plotForVelo),app.Errv_real(plotForVelo,:),'default',0,app.UIAxesNozzleVelocities);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            % Import the equations version
            eqLibrary = 'Velocity-driven';
            %             updateConsole(app,['Using '  eqLibrary ' library']);
            
            % Imports
            addpath(eqLibrary);
            addpath('tools');
            
            % Initializing nozzles dimensions table
            app.NozzlesDimensions.RowName = ['D (mm)';'L (mm)'];
            if ~isempty(app.NozzlesDimensions.Data) || ~isfile(app.prefFileName)
                updateTable(app,app.NbNozzleSpinner.Value());
            else
                % Initializing input preferences
                loadPreferences(app);  
            end
            
            % Initializing Plot options in drop down menus
            updatePlotForNozzle(app);
            updateVelocityDropDown(app);          
        end

        % Button pushed function: SelectmaterialdatabaseButton
        function SelectmaterialdatabaseButtonPushed(app, event)
            % Opening the material database file
            [file,path] = uigetfile('*.xls','Select the material database file to open');
            figure(app.ExtrusionpressurepredictionUIFigure)
            
            if isequal(file,0) % File was not opened
                %                 updateConsole(app,'User selected Cancel');
            else % File was opened
                %                 updateConsole(app,['User selected ' fullfile(path,file)]);
                app.matFile = [path file];
                
                % List of all the materials included in the database (sheets in the excel file)
                sheets = xl_xlsfinfo(app.matFile);
                app.AvailablematerialsListBox.Items = sheets(:,2);
            end
        end

        % Value changed function: AvailablematerialsListBox
        function AvailablematerialsListBoxValueChanged(app, event)
            value = app.AvailablematerialsListBox.Value;
            [app.rho, app.w, app.f, app.n, app.K, app.eta_inf, app.eta_0, app.tau_0, app.lambda, app.a] = readMaterial(app.matFile, value);
            
            %             updateConsole(app,['User selected : ' value]);
            
            % Enabling the compute button
            if strcmp(app.ComputeButton.Enable,'off')
                app.ComputeButton.Enable = 'on';
            end
            
            resetComputeButton(app);
        end

        % Value changed function: NbNozzleSpinner
        function NbNozzleSpinnerValueChanged(app, event)
            value = app.NbNozzleSpinner.Value;
            updateTable(app,value);
            updatePlotForNozzle(app);
            
            resetComputeButton(app);
        end

        % Value changed function: SetallD
        function SetallDValueChanged(app, event)
            value = app.SetallD.Value;
            changeTableValues(app,1,value);
            
            resetComputeButton(app);
        end

        % Value changed function: SetallL
        function SetallLValueChanged(app, event)
            value = app.SetallL.Value;
            changeTableValues(app,2,value);
            
            resetComputeButton(app);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % Set the compute button background color to yellow to indicate the user he needs to recompute
            app.ComputeButton.BackgroundColor = [0.9600    0.9600    0.9600]; % Default normal button color
            
            % Compute overall P for desired nozzle exit speed and retrieve viscosity/shear rate data
            % for each P/v
            P_amb = app.Pambient.Value;
            nozzleDim = get(app.NozzlesDimensions,'Data');
%             D = [cell2mat(nozzleDim(1,:));zeros(1,app.NbNozzleSpinner.Value)];
%             L = [cell2mat(nozzleDim(2,:));zeros(1,app.NbNozzleSpinner.Value)];
            D = [cell2mat(nozzleDim(1,:)) ; std(cell2mat(nozzleDim(1,:)),1) * ones(1,app.NbNozzleSpinner.Value)];
            L = [cell2mat(nozzleDim(2,:)) ; std(cell2mat(nozzleDim(2,:)),1) * ones(1,app.NbNozzleSpinner.Value)];
            app.v = str2num(app.NozzleVelocities.Value);
            
            app.P = zeros(size(app.v,2),1);             % To plot the pressures
            app.eta = zeros(size(app.v,2),size(D,2));   % To plot the viscosity
            app.SR = zeros(size(app.v,2),size(D,2));    % To plot the shear rate
            app.Q = zeros(size(app.v,2),size(D,2));     % To re-calculate the true velocities
            app.v_all = zeros(size(app.v,2),size(D,2)); % To bar plot the velocities comparison
            app.q_theo = zeros(size(app.v,2),size(D,2)); % To bar plot the velocities comparison
            app.Errv_real = zeros(size(app.v,2),size(D,2)); % To bar plot the velocities comparison
            app.dRi = zeros(size(app.v,2),size(D,2));
            app.dP = zeros(size(app.v,2),1);
            app.deta = zeros(size(app.v,2),size(D,2));
            app.dSR = zeros(size(app.v,2),size(D,2));
            
            % Pressure computation
            for i=1:1:size(app.v,2)
                [newP,newEta,newSR,newQ,newdEta,newdP,newdRi,newdSR] = generateP(app.rho, app.v(i), D, L, ...
                    app.n, app.K, app.eta_0, app.eta_inf, app.tau_0, app.lambda, app.a, P_amb, app.debug_mode);
                if isnan(newP)
                    error('Pressure could not be computed due to invalid Reynolds number');
                else
                    app.P(i) = newP;
                    app.eta(i,:) = newEta;
                    app.SR(i,:) = newSR;
                    app.Q(i,:) = newQ;
                    app.dP(i) = newdP;
                    app.dRi(i,:) = newdRi;
                    app.deta(i,:) = newdEta;
                    app.dSR(i,:) = newdSR;
                end
            end
            
            % True velocity and flow computation for nozzle true applied pressure
            % for each P/v combination
            for i=1:1:size(app.v,2)
                [v_real, Q_real, dv_real,q_long] = generateVreal(app.P(i), app.dP(i,:), app.Q(i,:), app.rho, app.v(i),D, L, ...
                    app.n, app.K, app.eta_0, app.eta_inf, app.tau_0, app.lambda, app.a, P_amb, app.debug_mode);
                app.v_all(i,:) = v_real;
                app.Errv_real(i,:) = dv_real;
                app.q_theo(i,:) = q_long;
            end
            
            % Plots
            app.P = app.P./1000; % convert Pa to kPa and plot
            app.dP = app.dP./1000; % convert Pa to kPa and plot
            
            makeGraph(app);
            app.ExportalldataButton.Enable = 'on';
            app.ExportgraphButton.Enable = 'on';
            app.AllCheckBox.Enable = 'on';
        end

        % Value changed function: NozzleVelocities
        function NozzleVelocitiesValueChanged(app, event)
            updateVelocityDropDown(app);
%             makeGraph(app);
            
            resetComputeButton(app);
        end

        % Value changed function: PlotgraphDropDown
        function PlotgraphDropDownValueChanged(app, event)
            if strcmp(app.ComputeButton.Enable,'on')
                makeGraph(app);
            end
            if strcmp(app.PlotgraphDropDown.Value,'Viscosity')
                app.PlotfornozzleDropDown.Enable = 'on';
                app.PlotfornozzleDropDownLabel.Enable = 'on';
            else
                app.PlotfornozzleDropDownLabel.Enable = 'off';
                app.PlotfornozzleDropDown.Enable = 'off';
            end
        end

        % Value changed function: PlotforvelocitymmsDropDown
        function PlotforvelocitymmsDropDownValueChanged(app, event)
            if strcmp(app.ComputeButton.Enable,'on')
                makeGraph(app);
            end
        end

        % Value changed function: PlotfornozzleDropDown
        function PlotfornozzleDropDownValueChanged(app, event)
            if strcmp(app.ComputeButton.Enable,'on')
                makeGraph(app);
            end
        end

        % Button pushed function: ExportgraphButton
        function ExportgraphButtonPushed(app, event)
            selected = app.SelectgraphforexportDropDown.Value;
            all = app.AllCheckBox.Value;
            
            if strcmp(selected,'Pressure')
                comparePlotPV(app.v,app.P,app.v,app.P_lit,[0 0],[0 0],app.dP,'default');
            elseif strcmp(selected,'Viscosity')
                if all
                    for i=1:app.NbNozzleSpinner.Value
                        comparePlotVisco(app.SR(:,i),app.eta(:,i),app.SR_lit,app.eta_lit,[0 0],[0 0],app.deta(:,i),app.dSR(:,i),'default',i);
                    end
                else
                    plotForNozzle = str2double(app.PlotfornozzleDropDown.Value);
                    comparePlotVisco(app.SR(:,plotForNozzle),app.eta(:,plotForNozzle),app.SR_lit,app.eta_lit,[0 0],[0 0],app.deta(:,plotForNozzle),app.dSR(:,plotForNozzle),'default',1);
                end
            else % Nozzle velocities
                if all
                    for i=1:size(app.PlotforvelocitymmsDropDown.Items,2)
                        compareBarPlotV(app.v_all(i,:),app.v(i),app.Errv_real(i,:),'default',i);
                    end
                else
                    plotForVelo = find(strcmp(app.PlotforvelocitymmsDropDown.Items,app.PlotforvelocitymmsDropDown.Value));
                    compareBarPlotV(app.v_all(plotForVelo,:),app.v(plotForVelo),app.Errv_real(plotForVelo,:),'default',1);
                end
            end
        end

        % Button pushed function: ExportalldataButton
        function ExportalldataButtonPushed(app, event)
            t = now;
            d = datetime(t,'ConvertFrom','datenum');
            [y,m,d] = ymd(d);
            
            filename = ['extrusion-P-predict_' app.AvailablematerialsListBox.Value '_' num2str(y) '-' num2str(m) '-' num2str(d) '.xlsx'];
            [filename,selpath] = uiputfile('.xlsx','Create a file to export the data',filename);
            savePath = [selpath '\' filename];
            
            if filename ~= 0
                T = table(app.PlotforvelocitymmsDropDown.Items',app.P,app.dP,'VariableNames',{'Velocity','P_all_nozzle','delta_P_nozzle'});
                writetable(T,savePath,'Sheet','Sheet1','WriteVariableNames',true);
                T = table(app.PlotforvelocitymmsDropDown.Items',app.eta,app.deta,'VariableNames',{'Velocity','eta_nozzle','delta_eta_nozzle'});
                writetable(T,savePath,'Sheet','Sheet2','WriteVariableNames',true);
                T = table(app.PlotforvelocitymmsDropDown.Items',app.SR,app.dSR,'VariableNames',{'Velocity','SR_nozzle','delta_SR_nozzle'});
                writetable(T,savePath,'Sheet','Sheet3','WriteVariableNames',true);
                T = table(app.PlotforvelocitymmsDropDown.Items',app.v_all,app.Errv_real,'VariableNames',{'Velocity','True_V','delta_V_nozzle'});
                writetable(T,savePath,'Sheet','Sheet4','WriteVariableNames',true);
                msgbox([filename ' was created in : ' selpath]);
            end
            figure(app.ExtrusionpressurepredictionUIFigure)
        end

        % Value changed function: Pambient
        function PambientValueChanged(app, event)
            resetComputeButton(app);
        end

        % Cell edit callback: NozzlesDimensions
        function NozzlesDimensionsCellEdit(app, event)
            resetComputeButton(app);
        end

        % Button pushed function: SaveinputsButton
        function SaveinputsButtonPushed(app, event)
            filename = app.prefFileName;
            tableData = get(app.NozzlesDimensions,'Data');
            T = table(app.NbNozzleSpinner.Value, app.SetallD.Value, app.SetallL.Value, app.NozzleVelocities.Value, app.Pambient.Value, join(string(tableData(1,:))), join(string(tableData(2,:))), 'VariableNames',{'nbNozzle','allD','allL','nozzleVelo','Pambient','tableD','tableL'});
            writetable(T,filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtrusionpressurepredictionUIFigure = uifigure('Visible', 'off');
            app.ExtrusionpressurepredictionUIFigure.Name = 'Extrusion pressure prediction';
            app.AvailablematerialsListBoxLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.AvailablematerialsListBoxLabel.Text = 'Available materials';
            app.AvailablematerialsListBox = uilistbox(app.ExtrusionpressurepredictionUIFigure);
            app.AvailablematerialsListBox.Items = {};
            app.AvailablematerialsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablematerialsListBoxValueChanged, true);
            app.AvailablematerialsListBox.Value = {};
            app.NozzlesDimensions = uitable(app.ExtrusionpressurepredictionUIFigure);
            app.NozzlesDimensions.ColumnName = {'Nozzle 1'};
            app.NozzlesDimensions.RowName = {};
            app.NozzlesDimensions.ColumnEditable = true;
            app.NozzlesDimensions.CellEditCallback = createCallbackFcn(app, @NozzlesDimensionsCellEdit, true);
            app.NbNozzleSpinnerLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.NbNozzleSpinnerLabel.Text = 'Nb Nozzle';
            app.NbNozzleSpinner = uispinner(app.ExtrusionpressurepredictionUIFigure);
            app.NbNozzleSpinner.Limits = [1 Inf];
            app.NbNozzleSpinner.ValueDisplayFormat = '%.0f';
            app.NbNozzleSpinner.ValueChangedFcn = createCallbackFcn(app, @NbNozzleSpinnerValueChanged, true);
            app.NbNozzleSpinner.Value = 1;
            app.UIAxesPV = uiaxes(app.ExtrusionpressurepredictionUIFigure);
            xlabel(app.UIAxesPV, 'Desired nozzle exit velocity (mm/s)')
            ylabel(app.UIAxesPV, 'Required pressure (kPa)')
            app.SelectmaterialdatabaseButton = uibutton(app.ExtrusionpressurepredictionUIFigure, 'push');
            app.SelectmaterialdatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmaterialdatabaseButtonPushed, true);
            app.SelectmaterialdatabaseButton.Text = 'Select material database';
            app.ComputeButton = uibutton(app.ExtrusionpressurepredictionUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.UIAxesNozzleVelocities = uiaxes(app.ExtrusionpressurepredictionUIFigure);
            xlabel(app.UIAxesNozzleVelocities, 'Nozzle #')
            ylabel(app.UIAxesNozzleVelocities, 'Nozzle exit velocity (mm/s)')
            app.SetallDEditFieldLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.SetallDEditFieldLabel.Text = 'Set all D';
            app.SetallD = uieditfield(app.ExtrusionpressurepredictionUIFigure, 'numeric');
            app.SetallD.LowerLimitInclusive = 'off';
            app.SetallD.Limits = [0 Inf];
            app.SetallD.ValueDisplayFormat = '%.3f';
            app.SetallD.ValueChangedFcn = createCallbackFcn(app, @SetallDValueChanged, true);
            app.SetallD.Value = 1;
            app.SetallLLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.SetallLLabel.Text = 'Set all L';
            app.SetallL = uieditfield(app.ExtrusionpressurepredictionUIFigure, 'numeric');
            app.SetallL.LowerLimitInclusive = 'off';
            app.SetallL.Limits = [0 Inf];
            app.SetallL.ValueDisplayFormat = '%.2f';
            app.SetallL.ValueChangedFcn = createCallbackFcn(app, @SetallLValueChanged, true);
            app.SetallL.Value = 1;
            app.AmbientPPaLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.AmbientPPaLabel.Text = 'Ambient P (Pa)';
            app.Pambient = uieditfield(app.ExtrusionpressurepredictionUIFigure, 'numeric');
            app.Pambient.LowerLimitInclusive = 'off';
            app.Pambient.Limits = [0 Inf];
            app.Pambient.ValueDisplayFormat = '%.2f';
            app.Pambient.ValueChangedFcn = createCallbackFcn(app, @PambientValueChanged, true);
            app.Pambient.Value = 1;
            app.DesirednozzlevmmsEditFieldLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.DesirednozzlevmmsEditFieldLabel.Text = 'Desired nozzle v (mm/s)';
            app.NozzleVelocities = uieditfield(app.ExtrusionpressurepredictionUIFigure, 'text');
            app.NozzleVelocities.ValueChangedFcn = createCallbackFcn(app, @NozzleVelocitiesValueChanged, true);
            app.NozzleVelocities.Value = '1';
            app.PlotgraphDropDownLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.PlotgraphDropDownLabel.Text = 'Plot graph';
            app.PlotgraphDropDown = uidropdown(app.ExtrusionpressurepredictionUIFigure);
            app.PlotgraphDropDown.Items = {'Pressure', 'Viscosity'};
            app.PlotgraphDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotgraphDropDownValueChanged, true);
            app.PlotgraphDropDown.Value = 'Pressure';
            app.PlotforvelocitymmsDropDownLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.PlotforvelocitymmsDropDownLabel.Text = 'Plot for velocity (mm/s)';
            app.PlotforvelocitymmsDropDown = uidropdown(app.ExtrusionpressurepredictionUIFigure);
            app.PlotforvelocitymmsDropDown.Items = {'999'};
            app.PlotforvelocitymmsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotforvelocitymmsDropDownValueChanged, true);
            app.PlotforvelocitymmsDropDown.Value = '999';
            app.PlotfornozzleDropDownLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.PlotfornozzleDropDownLabel.Enable = 'off';
            app.PlotfornozzleDropDownLabel.Text = 'Plot for nozzle';
            app.PlotfornozzleDropDown = uidropdown(app.ExtrusionpressurepredictionUIFigure);
            app.PlotfornozzleDropDown.Items = {'1'};
            app.PlotfornozzleDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotfornozzleDropDownValueChanged, true);
            app.PlotfornozzleDropDown.Enable = 'off';
            app.PlotfornozzleDropDown.Value = '1';
            app.ExportgraphButton = uibutton(app.ExtrusionpressurepredictionUIFigure, 'push');
            app.ExportgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ExportgraphButtonPushed, true);
            app.ExportgraphButton.Enable = 'off';
            app.ExportgraphButton.Text = 'Export graph';
            app.ExportalldataButton = uibutton(app.ExtrusionpressurepredictionUIFigure, 'push');
            app.ExportalldataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportalldataButtonPushed, true);
            app.ExportalldataButton.Enable = 'off';
            app.ExportalldataButton.Text = 'Export all data';
            app.SelectgraphforexportDropDownLabel = uilabel(app.ExtrusionpressurepredictionUIFigure);
            app.SelectgraphforexportDropDownLabel.Text = 'Select graph for export';
            app.SelectgraphforexportDropDown = uidropdown(app.ExtrusionpressurepredictionUIFigure);
            app.SelectgraphforexportDropDown.Items = {'Pressure', 'Viscosity', 'Nozzle velocities'};
            app.SelectgraphforexportDropDown.Value = 'Pressure';
            app.AllCheckBox = uicheckbox(app.ExtrusionpressurepredictionUIFigure);
            app.AllCheckBox.Enable = 'off';
            app.AllCheckBox.Text = 'All';
            app.AllCheckBox.Value = true;
            app.SaveinputsButton = uibutton(app.ExtrusionpressurepredictionUIFigure, 'push');
            app.SaveinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsButtonPushed, true);
            app.SaveinputsButton.Text = 'Save inputs';
            % Show the figure after all components are created
            app.ExtrusionpressurepredictionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extrusionPpredict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExtrusionpressurepredictionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtrusionpressurepredictionUIFigure)
        end
    end
end